classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        SSIMLabel      matlab.ui.control.Label
        PSNRLabel      matlab.ui.control.Label
        ProcessButton  matlab.ui.control.Button
        UploadButton   matlab.ui.control.Button
        DepthEstimate  matlab.ui.control.UIAxes
        DehazedImage   matlab.ui.control.UIAxes
        UploadImage    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % Get image.
            imageData = getimage(app.UploadImage);

            % Process image.
            [correctedImageData, thicknessOfHaze, ~] = utils.dehaze(im2double(imageData), 0.01, 0.9, 0.1);

            % Show the image.
            imshow(im2uint8(correctedImageData), 'Parent', app.DehazedImage);

            % Process depth estimate.
            eps = 1e-6;
            D = -log(1 - thicknessOfHaze + eps);

            % Show the depth estimate.
            imshow(im2uint8(D), 'Parent', app.DepthEstimate);

            % Calculate PSNR.
            peaksnr = psnr(imageData, im2uint8(correctedImageData));

            % Display PSNR.
            app.PSNRLabel.Text = sprintf("PSNR: %0.4g", peaksnr);

            % Calculate SSIM.
            ssimval = ssim(imageData, im2uint8(correctedImageData));

            % Display SSIM.
            app.SSIMLabel.Text = sprintf("SSIM: %0.4g", ssimval);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.bmp','All Image Files'};
            [filename, pathname] = uigetfile(filterspec);

           % If the user cancels, return.
            if ~ischar(filename)
                return;
            end

           % Construct the full file path.
           filepath = fullfile(pathname, filename);

           % Read the image.
           img = imread(filepath);

           % Show the image.
           imshow(img, 'Parent', app.UploadImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadImage = uiaxes(app.UIFigure);
            title(app.UploadImage, 'Upload Image')
            app.UploadImage.XTick = [];
            app.UploadImage.YAxisLocation = 'origin';
            app.UploadImage.YTick = [];
            app.DehazedImage = uiaxes(app.UIFigure);
            title(app.DehazedImage, 'Dehazed Image')
            app.DehazedImage.XTick = [];
            app.DehazedImage.YTick = [];
            app.DepthEstimate = uiaxes(app.UIFigure);
            title(app.DepthEstimate, 'Depth Estimate')
            app.DepthEstimate.XTick = [];
            app.DepthEstimate.YTick = [];
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.PSNRLabel = uilabel(app.UIFigure);
            app.PSNRLabel.Text = 'PSNR: 0';
            app.SSIMLabel = uilabel(app.UIFigure);
            app.SSIMLabel.Text = 'SSIM: 0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end